classdef guiApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TextArea                        matlab.ui.control.TextArea
        ImageOptionsDropDown            matlab.ui.control.DropDown
        ImageoptionsDropDownLabel       matlab.ui.control.Label
        ManualButton_2                  matlab.ui.control.Button
        ILabel_2                        matlab.ui.control.Label
        ILabel                          matlab.ui.control.Label
        SaveImageButton                 matlab.ui.control.Button
        ProjectionPanel                 matlab.ui.container.Panel
        ProjectionTooltipLabel          matlab.ui.control.Label
        MatrixInputEditField            matlab.ui.control.EditField
        MatrixInputEditFieldLabel       matlab.ui.control.Label
        ScalingPanel                    matlab.ui.container.Panel
        ScalingTooltipLabel             matlab.ui.control.Label
        RatioEditField                  matlab.ui.control.NumericEditField
        RatioEditFieldLabel             matlab.ui.control.Label
        RotationPanel                   matlab.ui.container.Panel
        RotationTooltipLabel            matlab.ui.control.Label
        Rotate90CounterclockwiseButton  matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        Rotate90ClockwiseButton         matlab.ui.control.Button
        AngleEditField                  matlab.ui.control.NumericEditField
        AngleEditFieldLabel             matlab.ui.control.Label
        TranslatePanel                  matlab.ui.container.Panel
        TranslateTooltipLabel           matlab.ui.control.Label
        InputYTranslateEditField        matlab.ui.control.NumericEditField
        InputYEditFieldLabel            matlab.ui.control.Label
        InputXTranslateEditField        matlab.ui.control.NumericEditField
        InputXEditFieldLabel            matlab.ui.control.Label
        TransformationsPanel            matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        ProjectionCheckBox              matlab.ui.control.CheckBox
        ProjectionLabel                 matlab.ui.control.Label
        ScalingCheckBox                 matlab.ui.control.CheckBox
        ScalingLabel                    matlab.ui.control.Label
        RotateCheckBox                  matlab.ui.control.CheckBox
        RotateLabel                     matlab.ui.control.Label
        TranslateCheckBox               matlab.ui.control.CheckBox
        TranslateLabel                  matlab.ui.control.Label
        ApplyTransformationsButton      matlab.ui.control.Button
        ResetImageButton                matlab.ui.control.Button
        LoadImageButton                 matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        image 
        transformedImage
        translateImageActivated = false
        rotateImageActivated = false
        scalingImageActivated = false
        transformCounter  = 0
        projectionImageActivated = false   
        ManualWin matlab.ui.Figure 
        translateApplied = false
    end
    
    
    methods (Access = public)
        
        function results = func(app)
            
        end
        
    function displayImageInNewWindow(app, image)
    % new figure window for displaying the image
    fig = figure;


    if isvalid(fig)
        % axes in the new figure
        ax = axes(fig);
        axis off

        imshow(image, 'Parent', ax);

        title(ax, 'Translated Image in New Window');
        set(fig, 'Position', [100, 100, 600, 400]);  % window size
    else
        disp('Error: Could not create a new figure window.');
    end
end
    end


    methods (Access = public)
    
    function applyRotation(app)
    % Get the rotation angle from user input
    angle = app.AngleEditField.Value;

    % Rotate the transformed image or the original image if not yet transformed
    if isempty(app.transformedImage)
        app.transformedImage = imrotate(app.image, angle, 'bilinear', 'crop');
    else
        app.transformedImage = imrotate(app.transformedImage, angle, 'bilinear', 'crop');
    end

    % Show the rotated image in UIAxes_2
    imshow(app.transformedImage, 'Parent', app.UIAxes_2, 'InitialMagnification', 'fit');
    %title(app.UIAxes_2, ['Rotated ', num2str(angle), '°']);
     end

        
    function applyTranslate(app)
    value = app.TranslateCheckBox.Value;


    % placeholder values
    if isempty(app.InputXTranslateEditField.Value) || isempty(app.InputYTranslateEditField.Value)
        app.InputXTranslateEditField.Value = 0;
        app.InputYTranslateEditField.Value = 0;
    end

    % translation values
    tx = app.InputXTranslateEditField.Value;
    ty = app.InputYTranslateEditField.Value;

    % printing input tx and ty values to command window
    disp(['Translation in X: ', num2str(tx)]);
    disp(['Translation in Y: ', num2str(ty)]);

    %  numerical input checking
    if isnan(tx) || isnan(ty)
        uialert(app.UIFigure, 'Please enter valid numbers for translation.', 'Invalid Input');
        app.TranslateButton.Enable = 'on';
        return;
    end

    % size of the original image
    [rows, cols, ~] = size(app.image);

    
    visiblePercentage = 0.2; 
    maxTxPos = cols * (1 - visiblePercentage);
    maxTxNeg = -cols * (1 - visiblePercentage);
    maxTyPos = rows * (1 - visiblePercentage);
    maxTyNeg = -rows * (1 - visiblePercentage);

    %  if translation would move the image too far out of bounds
    if tx > maxTxPos || tx < maxTxNeg || ty > maxTyPos || ty < maxTyNeg
        app.translateApplied = false
        uialert(app.UIFigure, 'Translation would move image too far out of bounds.', 'Invalid Translation');
        return;
    end
    
     if isempty(app.transformedImage)
            app.transformedImage = imtranslate(app.image,[tx,ty])
            axis(app.UIAxes_2, 'image');  
            app.UIAxes_2.XLimMode = 'auto';
            app.UIAxes_2.YLimMode = 'auto';
            imshow(app.transformedImage, 'Parent', app.UIAxes_2);


            app.translateApplied = true
        
     else
             app.translateApplied = true
            axis(app.UIAxes_2, 'image');  
            app.UIAxes_2.XLimMode = 'auto';
            app.UIAxes_2.YLimMode = 'auto';
            app.transformedImage = imtranslate(app.transformedImage,[tx,ty]); 
        end
             % this is to show the image when scale has been applied
           imshow(app.transformedImage, 'Parent', app.UIAxes_2);
           %title(app.UIAxes_2, ['Image translated by [', num2str(tx), ', ', num2str(ty), '] pixels']);
         
    end
    
    % this is the scaling function
    function applyScaling(app)
        % this is for getting the scale ratio from the user
        
        scale = app.RatioEditField.Value;

        % this checks if user entered a valid value
        if ~isnumeric(scale) || scale <= 0 || isnan(scale)
            uialert(app.UIFigure, 'Enter a positive number for the scale ratio.', 'Invalid Input');
            return;
        end

        % this is to apply scaling
        if isempty(app.transformedImage)
            app.transformedImage = imresize(app.image, scale);  
        else
            app.transformedImage = imresize(app.transformedImage, scale); 
        end

        % this is to show the image when scale has been applied
        imshow(app.transformedImage, 'Parent', app.UIAxes_2);
        %title(app.UIAxes_2, ['Image Scaled By ', num2str(scale)]);
    end

    function applyProjection(app)
        disp('applyProjection called');
        % If projection is not enabled, show the original image
        if ~app.ProjectionCheckBox.Value
            if ~isempty(app.image)
                imshow(app.image, 'Parent', app.UIAxes_2);
                title(app.UIAxes_2, 'Original Image (Projection Off)');
                axis(app.UIAxes_2, 'off'); % Turn off axes for original image as well if needed
                app.transformedImage = app.image;
            else
                cla(app.UIAxes_2);
                title(app.UIAxes_2, '');
                axis(app.UIAxes_2, 'off');
                app.transformedImage = [];
            end
            return;
        end
        % Get the projection matrix from the input field
        matrixStr = app.MatrixInputEditField.Value;
        try
            projectionMatrix = eval(matrixStr);
            if ~isequal(size(projectionMatrix), [3 3])
                uialert(app.UIFigure, 'Projection matrix must be 3x3.', 'Invalid Input');
                return;
            end
        catch
            uialert(app.UIFigure, 'Invalid matrix format. Please use [a b c; d e f; g h i].', 'Invalid Input');
            return;
        end
        % Check if an image is loaded
        if isempty(app.image)
            uialert(app.UIFigure, 'Please load an image first.', 'No Image');
            return;
        end
        % Create a projective2d transform object
        tform = projective2d(projectionMatrix'); % Note the transpose here
        try
            % Get the spatial referencing object based on whether an image
            % has already been transformed
            if isempty(app.transformedImage)
                imRef = imref2d(size(app.image));
                % Apply the projective transformation to the original image
                [projectedImage, projectedRef] = imwarp(app.image, imRef, tform);
            else
                imRef = imref2d(size(app.transformedImage));
                % Apply the projective transformation to the already transformed image
                [projectedImage, projectedRef] = imwarp(app.transformedImage, imRef, tform);
            end
            % Display the transformed image using the output reference object
            imshow(projectedImage, projectedRef, 'Parent', app.UIAxes_2);
            %title(app.UIAxes_2, 'Projected Image');
            % Turn off the axes to hide the range
            axis(app.UIAxes_2, 'off');
            app.transformedImage = projectedImage;
        catch ME
            uialert(app.UIFigure, ['Error applying projection: ', ME.message], 'Transformation Error');
            return;
        end
    end

    end
    
    methods (Access = private)
        
        %  clean up when manual window closes
    function closeManualWindow(app, src)
        delete(src)                    % close the figure
        app.ManualWin = matlab.ui.Figure.empty;  % clear the handle
    end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: LoadImageButton, UIFigure
        function LoadImageButtonPushed(app, event)
            
            % File dialog input from user
            
            % direct to starting folder
            startingFolder = pwd;
            if ~isfolder(startingFolder)

                startingFolder = pwd
            end

            if app.ImageOptionsDropDown.Value =="Peppers.png"
                app.image = imread("peppers.png")
                imshow(app.image, 'Parent', app.UIAxes,'InitialMagnification', 'fit');
                app.image = im2double(app.image);
                [rows, cols, ~] = size(app.image);
                app.ILabel.Text = sprintf('Image Size: %d x %d', rows, cols);
                %title(app.UIAxes, 'Loaded Image');
                app.ApplyTransformationsButton.Enable = 'on'
                app.RotateCheckBox.Enable = 'on'
                app.ScalingCheckBox.Enable = 'on'
                app.ProjectionCheckBox.Enable = 'on'
                app.TranslateCheckBox.Enable = 'on'
                return
            end

            if app.ImageOptionsDropDown.Value =="Rice.png"
                app.image = imread("rice.png")
                imshow(app.image, 'Parent', app.UIAxes,'InitialMagnification', 'fit');
                app.image = im2double(app.image);
                [rows, cols, ~] = size(app.image);
                app.ILabel.Text = sprintf('Image Size: %d x %d', rows, cols);
                title(app.UIAxes, 'Loaded Image');
                app.ApplyTransformationsButton.Enable = 'on'
                app.RotateCheckBox.Enable = 'on'
                app.ScalingCheckBox.Enable = 'on'
                app.ProjectionCheckBox.Enable = 'on'
                app.TranslateCheckBox.Enable = 'on'
                return
            end

            if app.ImageOptionsDropDown.Value == "Bag.png"
                app.image = imread('bag.png')
                imshow(app.image, 'Parent', app.UIAxes,'InitialMagnification', 'fit');
                app.image = im2double(app.image);
                [rows, cols, ~] = size(app.image);
                app.ILabel.Text = sprintf('Image Size: %d x %d', rows, cols);
                title(app.UIAxes, 'Loaded Image');
                app.ApplyTransformationsButton.Enable = 'on'
                app.RotateCheckBox.Enable = 'on'
                app.ScalingCheckBox.Enable = 'on'
                app.ProjectionCheckBox.Enable = 'on'
                app.TranslateCheckBox.Enable = 'on'
                return
            end

               
            % Creating file path 
            defaultFileName = fullfile(startingFolder,'*.*')
            % declare valid extensions 

            [baseFileName, folder] = uigetfile({'*.png;*.jpg;*.gif', 'Image Files (*.png, *.jpg, *.gif)'}, ...
            defaultFileName, 'Select Image file');            % dialog canceled 
            if baseFileName == 0
                return;
            end

            %  user does not input invalid image file %


            fullFileName = fullfile(folder,baseFileName)
            app.image = imread(fullFileName)
            % displaying the image in UIAxes
            
          
            imshow(app.image, 'Parent', app.UIAxes,'InitialMagnification', 'fit');
            app.image = im2double(app.image);
            [rows, cols, ~] = size(app.image);
            app.ILabel.Text = sprintf('Image Size: %d x %d', rows, cols);

            axis off
            
                        
            % Fix axis settings
            axis(app.UIAxes, 'image');
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
   
            %title(app.UIAxes, 'Loaded Image');
            % imshow(app.image)
            % enable buttons after loading\
            app.ApplyTransformationsButton.Enable = 'on'
            app.RotateCheckBox.Enable = 'on'
            app.ScalingCheckBox.Enable = 'on'
            app.ProjectionCheckBox.Enable = 'on'
            app.TranslateCheckBox.Enable = 'on'

        end

        % Button pushed function: ResetImageButton
        function ResetImageButtonPushed(app, event)
             if isempty(app.image)
                 uialert(app.UIFigure, 'No image to reset.', 'Error');
             return;
             end

        % Clear UIAxes_2 (transformed image preview)
         cla(app.UIAxes_2);                      % Clear axes
         title(app.UIAxes_2, '');                % Clear title
         axis(app.UIAxes_2, 'off');              % Hide axes visuals

        % Re-display original image in UIAxes (original image view)
        imshow(app.image, 'Parent', app.UIAxes);
        %title(app.UIAxes, 'Original Image');
        
        % Clear transformations
        transformCounter = 0
        
        app.ILabel_2.Text = '' 
        % Reset transformation variables
        app.transformedImage = app.image;              % Clear any transformed image
        app.translateImageActivated = false;
        app.rotateImageActivated = false;
        app.scalingImageActivated = false;
        app.projectionImageActivated = false;
        app.transformCounter = 0;

        % Reset input fields and checkboxes


        app.TranslateCheckBox.Value = false;
        app.RotateCheckBox.Value = false;
        app.ScalingCheckBox.Value = false;
        app.InputXTranslateEditField.Enable = 'off';
        app.InputYTranslateEditField.Enable = 'off';
        app.AngleEditField.Enable = 'off'
       
        app.ProjectionCheckBox.Value = false;
        app.AngleEditField.Value = 0; 
        app.RatioEditField.Value = 0;          
        app.RatioEditField.Enable = 'off';
        app.MatrixInputEditField.Enable = 'off';
        app.MatrixInputEditField.Value = '[1 0 0; 0 1 0; 0 0 1]';
        app.TextArea.Value = '';

            % Implement buttoon logic

        end

        % Value changed function: TranslateCheckBox
        function TranslateCheckBoxValueChanged(app, event)
    value = app.TranslateCheckBox.Value;
    app.translateImageActivated = value


    if value == 1
        %  button is toggled on
        app.InputXTranslateEditField.Enable = 'on'
        app.InputYTranslateEditField.Enable = 'on'
        app.transformCounter = app.transformCounter + 1
    else
        app.InputXTranslateEditField.Enable = 'off'
        app.InputYTranslateEditField.Enable = 'off'
        app.transformCounter = app.transformCounter - 1

    end


    % re-enable buttons after translation
    % app.LoadImageButton.Enable = 'on';
    % app.Button.Enable = 'on';
    % app.ResizeButton.Enable = 'on';
        end

        % Value changed function: RotateCheckBox
        function RotateCheckBoxValueChanged(app, event)
            value = app.RotateCheckBox.Value
            app.rotateImageActivated = value
            app.transformCounter = app.transformCounter + 1
            if value == 1
                
                % Update global transform counter %
                app.transformCounter + 1
                app.AngleEditField.Enable = 'on'
                app.Rotate90ClockwiseButton.Enable = 'on'
                app.Button.Enable = 'on'
                app.Rotate90CounterclockwiseButton.Enable = 'on'
            else
                app.transformCounter - 1
                app.AngleEditField.Enable = 'off'
                app.Rotate90ClockwiseButton.Enable = 'off'
                app.Button.Enable = 'off'
                app.Rotate90CounterclockwiseButton.Enable = 'off'
                app.transformCounter - 1
            end


        end

        % Button pushed function: ApplyTransformationsButton
        function ApplyTransformationsButtonPushed(app, event)
            % Check Values of all for 4 check boxes and apply
            % transformations as needed
            % Reflection, Scaling, Rotation, Translation
            disp(app.transformCounter)
            
            if app.transformCounter < 1
                uialert(app.UIFigure, "Select one atleast one transformation","Not enough transformations")
                return 
            end

            if app.transformCounter > 3
                uialert(app.UIFigure,"Only up to 3 transformations at a time allowed", 'Too many transformations')
                return
            end

            transformationOrder = {}; % To store the order of transformations
            transformationMessages = ''; % To accumulate messages for the GUI

            % Apply transformations based on checkbox states and record the order
            if app.rotateImageActivated
                % do rotation
                if isempty(app.image)
                    uialert(app.UIFigure, 'Please load an image first.', 'No Image');
                    return;
                end
                app.applyRotation();
                transformationOrder{end+1} = 'Rotate';
                message = sprintf('Rotated %.2f°', app.AngleEditField.Value);
                transformationMessages = [transformationMessages, message, ' -> '];
                % The title is set within applyRotation, no need to duplicate here
            end
            % for most at 3 transformations %
            if app.translateImageActivated
                % do translation
                app.applyTranslate();
                if app.translateApplied == true
                transformationOrder{end+1} = 'Translate';
                message = sprintf('Image translated by [%.2f, %.2f] pixels', ...
                                  app.InputXTranslateEditField.Value, ...
                                  app.InputYTranslateEditField.Value);
                transformationMessages = [transformationMessages, message, ' -> '];
                % The title is set within applyTranslate
                end
            end
            if app.scalingImageActivated
                % this is to do scaling
                app.applyScaling();
                transformationOrder{end+1} = 'Scale';
                message = sprintf('Image Scaled By %.2f', app.RatioEditField.Value);
                transformationMessages = [transformationMessages, message, ' -> '];
                % The title is set within applyScaling
            end
            if app.projectionImageActivated
                % do projection
                app.applyProjection();
                transformationOrder{end+1} = 'Project';
                transformationMessages = [transformationMessages, 'Projected Image'];
                % The title is set within applyProjection
            end

            % Update the GUI text area with the transformation messages
            disp(fieldnames(app));
            if isprop(app, 'TextArea')
                disp('Updating text area');
                app.TextArea.Value = transformationMessages;
            end

            [rows, cols, ~] = size(app.transformedImage);
            app.ILabel_2.Text = ['Image Size: ', num2str(rows), ' x ', num2str(cols)];

            % Display the order of transformations in the command window
            if ~isempty(transformationOrder)
                orderStr = strjoin(transformationOrder, ' -> ');
                disp(['Applied Transformations Order: ', orderStr]);
            else
                disp('No transformations applied.');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: Rotate90ClockwiseButton
        function Rotate90ClockwiseButtonPushed(app, event)
         if isempty(app.image)
              uialert(app.UIFigure, 'Please load an image first.', 'No Image');
             return;
         end

            app.transformedImage = imrotate(app.image, -90, 'bilinear', 'crop');
            imshow(app.transformedImage, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, 'Rotated 90° Clockwise');
            
        end

        % Button pushed function: Rotate90CounterclockwiseButton
        function Rotate90CounterclockwiseButtonPushed(app, event)
             if isempty(app.image)
                 uialert(app.UIFigure, 'Please load an image first.', 'No Image');
                 return;
             end

            app.transformedImage = imrotate(app.image, 90, 'bilinear', 'crop');
            imshow(app.transformedImage, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, 'Rotated 90° CounterClockwise');
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             if isempty(app.image)
                uialert(app.UIFigure, 'Please load an image first.', 'No Image');
             return;
             end

            app.transformedImage = imrotate(app.image, 180, 'bilinear', 'crop');
            imshow(app.transformedImage, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, 'Rotated 180° Clockwise');
            
        end

        % Value changed function: RatioEditField
        function RatioEditFieldValueChanged(app, event)
        % TYPO edit out % 
        end

        % Value changed function: ScalingCheckBox
        function ScalingCheckBoxValueChanged(app, event)
            value = app.ScalingCheckBox.Value;
            app.scalingImageActivated = value; 
    
            if value == 1
                app.RatioEditField.Enable = 'on';
                app.transformCounter = app.transformCounter + 1;
            else
                app.RatioEditField.Enable = 'off';  
                app.transformCounter = app.transformCounter - 1;
            end
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
         if isempty(app.transformedImage)
        uialert(app.UIFigure, 'No transformed image to save.', 'Error');
        return;
    end

    % Open file dialog for saving the image
    [file, path] = uiputfile({'*.png'; '*.jpg'; '*.bmp'}, 'Save Image As');
    if isequal(file, 0)
        return; % User canceled
    end

    % Save the transformed image
    imwrite(app.transformedImage, fullfile(path, file));
    uialert(app.UIFigure, 'Image saved successfully.', 'Success');

        end

        % Value changed function: AngleEditField
        function AngleEditFieldValueChanged(app, event)
        value = app.AngleEditField.Value;

    if value < 0 || value > 360
        uialert(app.UIFigure, 'Please enter an angle between 0 and 360 degrees.', 'Invalid Input');
        app.AngleEditField.Value = 0; % Reset to default value
    end

        end

        % Value changed function: ProjectionCheckBox
        function ProjectionCheckBoxValueChanged(app, event)
            value = app.ProjectionCheckBox.Value;
            app.projectionImageActivated = value;
        if value == 1
            app.MatrixInputEditField.Enable = "on"
            app.transformCounter = app.transformCounter + 1;
        else
            app.MatrixInputEditField.Enable = "off"
            app.transformCounter = app.transformCounter - 1;
        end
        end

        % Button pushed function: ManualButton_2
        function ManualButton_2Pushed(app, event)
            f = figure;
               % If manual window already exists and is valid, just bring it to front
    if ~isempty(app.ManualWin) && isvalid(app.ManualWin)
        figure(app.ManualWin)
        return
    end

    %  create the manual window
    app.ManualWin = uifigure( ...
        'Name',     'User Manual', ...
        'Position', [400 300 600 400] );

    %  intercept the close request so we clear the handle
    app.ManualWin.CloseRequestFcn = @(src,ev) closeManualWindow(app, src);

    %  tab grouping in the manual window
    tg = uitabgroup(app.ManualWin, 'Position', [10 10 580 380]);

    tab1 = uitab(tg, 'Title', 'Overview');
    uilabel(tab1, ...
        'Position', [20 280 540 60], ...
        'Text', sprintf(['Welcome! This app lets you explore and apply basic image transformations.\n' ...
        'This application supports translating, rotating, scaling, and applying projective transformations\n' ...
         ]), ...
        'FontSize', 13, ...
        'HorizontalAlignment', 'left', ...
        'VerticalAlignment', 'top');

    tab2 = uitab(tg,'Title','Transformations Overview')
    uilabel(tab2, ...
    'Position', [20 240 540 140], ...
    'Text', sprintf(['Transformations Overview:\n\n' ...
    '1. Translate   → Move the image left, right, up, or down.\n' ...
    '2. Rotate      → Spin the image around a central point.\n' ...
    '3. Scale       → Resize the image larger or smaller.\n' ...
    '4. Projection  → Warp the image using perspective.']), ...
    'FontSize', 13, ...
    'FontWeight', 'normal', ...
    'HorizontalAlignment', 'left', ...
    'VerticalAlignment', 'top');


  % third tab: Built-in images
    tab3 = uitab(tg, 'Title', 'Built-In Images');

    uilabel(tab3, ...
    'Position', [20 320 540 30], ...
    'Text', ' Built-in Image Options', ...
    'FontSize', 16, ...
    'FontWeight', 'bold', ...
    'HorizontalAlignment', 'left', ...
    'BackgroundColor', 'white');

    
     % shift down by 20 %
    img = uiimage(tab3);
    img.Position = [20 135 180 180];  % x, y, width, height
    img.ImageSource = 'peppers.png';
    
  
    uilabel(tab3, ...
        'Text', 'Peppers', ...
        'Position', [20 275 180 30], ...  
        'HorizontalAlignment', 'center', ...
        'FontWeight', 'bold', ...
        'BackgroundColor', 'white', ...
        'FontSize', 14);

    img2 = uiimage(tab3);
    img2.Position = [235 140 150 180];  % added more space between images
    img2.ImageSource = 'rice.png';
    
    % 280
    uilabel(tab3, ...
        'Text', 'Rice', ...
        'Position', [235 275 150 30], ...
        'HorizontalAlignment', 'center', ...
        'FontWeight', 'bold', ...
        'BackgroundColor', 'white', ...
        'FontSize', 14);

    %  180
    img3 = uiimage(tab3)
    img3.Position = [415, 140, 150, 160];  % [450, 140, 150, 180]
    img3.ImageSource = 'bag.png';  % change to your image name
    
    % Label for the second image
    uilabel(tab3, ...
        'Text', 'Bag', ...
        'Position', [430, 275, 120, 30], ...
        'HorizontalAlignment', 'center', ...
        'FontWeight', 'bold', ...
        'BackgroundColor', 'white', ...
        'FontSize', 14);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.Visible = 'off';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Icon = fullfile(pathToMLAPP, 'upload.png');
            app.LoadImageButton.Text = 'Load  Image';
            app.ResetImageButton = uibutton(app.UIFigure, 'push');
            app.ResetImageButton.ButtonPushedFcn = createCallbackFcn(app, @ResetImageButtonPushed, true);
            app.ResetImageButton.Icon = fullfile(pathToMLAPP, 'reset.png');
            app.ResetImageButton.Text = 'Reset  Image';
            app.ApplyTransformationsButton = uibutton(app.UIFigure, 'push');
            app.ApplyTransformationsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTransformationsButtonPushed, true);
            app.ApplyTransformationsButton.Enable = 'off';
            app.ApplyTransformationsButton.Text = 'Apply Transformations';
            app.TransformationsPanel = uipanel(app.UIFigure);
            app.TransformationsPanel.Title = 'Transformations';
            app.TranslateLabel = uilabel(app.TransformationsPanel);
            app.TranslateLabel.Text = 'Translate';
            app.TranslateCheckBox = uicheckbox(app.TransformationsPanel);
            app.TranslateCheckBox.ValueChangedFcn = createCallbackFcn(app, @TranslateCheckBoxValueChanged, true);
            app.TranslateCheckBox.Enable = 'off';
            app.RotateLabel = uilabel(app.TransformationsPanel);
            app.RotateLabel.Text = 'Rotate';
            app.RotateCheckBox = uicheckbox(app.TransformationsPanel);
            app.RotateCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotateCheckBoxValueChanged, true);
            app.RotateCheckBox.Enable = 'off';
            app.ScalingLabel = uilabel(app.TransformationsPanel);
            app.ScalingLabel.Text = 'Scaling';
            app.ScalingCheckBox = uicheckbox(app.TransformationsPanel);
            app.ScalingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScalingCheckBoxValueChanged, true);
            app.ScalingCheckBox.Enable = 'off';
            app.ProjectionLabel = uilabel(app.TransformationsPanel);
            app.ProjectionLabel.Text = 'Projection';
            app.ProjectionCheckBox = uicheckbox(app.TransformationsPanel);
            app.ProjectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProjectionCheckBoxValueChanged, true);
            app.ProjectionCheckBox.Enable = 'off';
            app.Label = uilabel(app.TransformationsPanel);
            app.Label.Tooltip = {'Choose any of the four options to apply transformations. Up to 3 transformations at a time.'};
            app.Label.Text = 'ⓘ';
            app.TranslatePanel = uipanel(app.UIFigure);
            app.TranslatePanel.Title = 'Translate';
            app.InputXEditFieldLabel = uilabel(app.TranslatePanel);
            app.InputXEditFieldLabel.Text = 'Input X';
            app.InputXTranslateEditField = uieditfield(app.TranslatePanel, 'numeric');
            app.InputXTranslateEditField.Enable = 'off';
            app.InputYEditFieldLabel = uilabel(app.TranslatePanel);
            app.InputYEditFieldLabel.Text = 'Input Y';
            app.InputYTranslateEditField = uieditfield(app.TranslatePanel, 'numeric');
            app.InputYTranslateEditField.Enable = 'off';
            app.TranslateTooltipLabel = uilabel(app.TranslatePanel);
            app.TranslateTooltipLabel.Tooltip = {'Input X & Y value to move the image left, right, up, or down.'};
            app.TranslateTooltipLabel.Text = 'ⓘ';
            app.RotationPanel = uipanel(app.UIFigure);
            app.RotationPanel.Title = 'Rotation';
            app.AngleEditFieldLabel = uilabel(app.RotationPanel);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.RotationPanel, 'numeric');
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @AngleEditFieldValueChanged, true);
            app.AngleEditField.Enable = 'off';
            app.Rotate90ClockwiseButton = uibutton(app.RotationPanel, 'push');
            app.Rotate90ClockwiseButton.ButtonPushedFcn = createCallbackFcn(app, @Rotate90ClockwiseButtonPushed, true);
            app.Rotate90ClockwiseButton.Icon = fullfile(pathToMLAPP, 'rotate-right.png');
            app.Rotate90ClockwiseButton.Enable = 'off';
            app.Rotate90ClockwiseButton.Text = ' 90° ';
            app.Button = uibutton(app.RotationPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Enable = 'off';
            app.Button.Text = ' 180°';
            app.Rotate90CounterclockwiseButton = uibutton(app.RotationPanel, 'push');
            app.Rotate90CounterclockwiseButton.ButtonPushedFcn = createCallbackFcn(app, @Rotate90CounterclockwiseButtonPushed, true);
            app.Rotate90CounterclockwiseButton.Icon = fullfile(pathToMLAPP, 'rotate-left.png');
            app.Rotate90CounterclockwiseButton.Enable = 'off';
            app.Rotate90CounterclockwiseButton.Text = '90°';
            app.RotationTooltipLabel = uilabel(app.RotationPanel);
            app.RotationTooltipLabel.Tooltip = {'Input a value or choose a preset option to spin the image around a central point.'};
            app.RotationTooltipLabel.Text = 'ⓘ';
            app.ScalingPanel = uipanel(app.UIFigure);
            app.ScalingPanel.Title = 'Scaling';
            app.RatioEditFieldLabel = uilabel(app.ScalingPanel);
            app.RatioEditFieldLabel.Text = 'Ratio';
            app.RatioEditField = uieditfield(app.ScalingPanel, 'numeric');
            app.RatioEditField.ValueChangedFcn = createCallbackFcn(app, @RatioEditFieldValueChanged, true);
            app.RatioEditField.Enable = 'off';
            app.ScalingTooltipLabel = uilabel(app.ScalingPanel);
            app.ScalingTooltipLabel.Tooltip = {'Input a value to resize the image larger or smaller.'};
            app.ScalingTooltipLabel.Text = 'ⓘ';
            app.ProjectionPanel = uipanel(app.UIFigure);
            app.ProjectionPanel.Title = 'Projection';
            app.MatrixInputEditFieldLabel = uilabel(app.ProjectionPanel);
            app.MatrixInputEditFieldLabel.Enable = 'off';
            app.MatrixInputEditFieldLabel.Text = 'Matrix Input';
            app.MatrixInputEditField = uieditfield(app.ProjectionPanel, 'text');
            app.MatrixInputEditField.Enable = 'off';
            app.MatrixInputEditField.Value = '[1 0 0; 0 1 0; 0 0 1]';
            app.ProjectionTooltipLabel = uilabel(app.ProjectionPanel);
            app.ProjectionTooltipLabel.Tooltip = {'Input a value to warp the image using perspective.'};
            app.ProjectionTooltipLabel.Text = 'ⓘ';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.ILabel = uilabel(app.UIFigure);
            app.ILabel.Text = '';
            app.ILabel_2 = uilabel(app.UIFigure);
            app.ILabel_2.Text = '';
            app.ManualButton_2 = uibutton(app.UIFigure, 'push');
            app.ManualButton_2.ButtonPushedFcn = createCallbackFcn(app, @ManualButton_2Pushed, true);
            app.ManualButton_2.Text = 'Manual';
            app.ImageoptionsDropDownLabel = uilabel(app.UIFigure);
            app.ImageoptionsDropDownLabel.Text = 'Image options';
            app.ImageOptionsDropDown = uidropdown(app.UIFigure);
            app.ImageOptionsDropDown.Items = {'Choose from owned images', 'Peppers.png', 'Rice.png', 'Bag.png', ''};
            app.ImageOptionsDropDown.Value = 'Choose from owned images';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Placeholder = 'Applied Transformations';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end