classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ScalingCheckBox_2               matlab.ui.control.CheckBox
        ReflectionLabel                 matlab.ui.control.Label
        ScalingCheckBox                 matlab.ui.control.CheckBox
        ScalingLabel                    matlab.ui.control.Label
        RotateAngleEditField            matlab.ui.control.NumericEditField
        RotateAngleEditFieldLabel       matlab.ui.control.Label
        ApplyTransformationsButton      matlab.ui.control.Button
        RotateCheckBox                  matlab.ui.control.CheckBox
        RotateLabel                     matlab.ui.control.Label
        TranslateCheckBox               matlab.ui.control.CheckBox
        TranslateLabel                  matlab.ui.control.Label
        ResetTransormationsImageButton  matlab.ui.control.Button
        InputYTranslateEditField        matlab.ui.control.NumericEditField
        InputYEditFieldLabel            matlab.ui.control.Label
        InputXTranslateEditField        matlab.ui.control.NumericEditField
        InputXEditFieldLabel            matlab.ui.control.Label
        LoadImageButton                 matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        image % Description
        transformedImage
        translateImageActivated = 'false'
        rotateImageActivated = 'false'
        transformCounter  = 'false'
     
    end
    
    methods (Access = public)
        
        function results = func(app)
            
        end
        
    function displayImageInNewWindow(app, image)
    % Create a new figure window for displaying the image
    fig = figure;

    % Check if the figure is valid before proceeding
    if isvalid(fig)
        % Create axes in the new figure
        ax = axes(fig);
        axis off

        % Display the image in the new axes
        imshow(image, 'Parent', ax);

        % Optionally, customize the window (title, size)
        title(ax, 'Translated Image in New Window');
        set(fig, 'Position', [100, 100, 600, 400]);  % Set window size
    else
        disp('Error: Could not create a new figure window.');
    end
end
    end


    methods (Access = public)
        
    function applyTranslate(app)
    value = app.TranslateCheckBox.Value;


    % Default placeholder values
    if isempty(app.InputXTranslateEditField.Value) || isempty(app.InputYTranslateEditField.Value)
        app.InputXTranslateEditField.Value = 0;
        app.InputYTranslateEditField.Value = 0;
    end

    % Get translation values
    tx = app.InputXTranslateEditField.Value;
    ty = app.InputYTranslateEditField.Value;

    % Print tx and ty values to command window
    disp(['Translation in X: ', num2str(tx)]);
    disp(['Translation in Y: ', num2str(ty)]);

    % Validate numerical input
    if isnan(tx) || isnan(ty)
        uialert(app.UIFigure, 'Please enter valid numbers for translation.', 'Invalid Input');
        app.TranslateButton.Enable = 'on';
        return;
    end

    % Get the size of the original image
    [rows, cols, ~] = size(app.image);

    % Create the affine transformation matrix
    tform = affine2d([1 0 0; 0 1 0; tx ty 1]);

    % Apply the transformation to the image using imwarp
    % Use the original image size to prevent changing the size
    outputView = imref2d([rows, cols]);
    
    % Apply the translation and maintain the original image size
    app.transformedImage = imwarp(app.image, tform, 'OutputView', outputView);  

    % Display the transformed image in the UI
    title(app.UIAxes_2, 'Transformed Image');
    imshow(app.transformedImage, 'Parent', app.UIAxes_2);

    % Optional: You can add a title or labels to the axes
    % title(app.UIAxes, 'Translated Image');
    % app.UIAxes.XLabel.String = '';
    % app.UIAxes.YLabel.String = '';

    % Create a new figure window for the translated image
    % figureHandleTranslated = figure('Name', 'Translated Image', 'NumberTitle', 'off', 'Position', [100, 100, 800, 600]);
    % imshow(app.transformedImage);  % Display the transformed image in the new window
    % title('Translated Image in New Window');
    % set(figureHandleTranslated, 'Position', [200, 200, 900, 700]);  % Set the size of the new window for the translated image


    % Re-enable buttons after translation
    % app.LoadImageButton.Enable = 'on';
    % app.Button3.Enable = 'on';
    % app.ResizeButton.Enable = 'on';

         
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: LoadImageButton, UIFigure
        function LoadImageButtonPushed(app, event)
            
            % Get File dialog input from user
            
            % direct to starting folder
            startingFolder = pwd;
            if ~isfolder(startingFolder)

                startingFolder = pwd
            end
            % Creating file path 
            defaultFileName = fullfile(startingFolder,'*.*')
            % declare valid extensions 

            [baseFileName, folder] = uigetfile({'*.png;*.jpg;*.gif', 'Image Files (*.png, *.jpg, *.gif)'}, ...
            defaultFileName, 'Select Image file');            % If dialog canceled
            if baseFileName == 0
                return;
            end

            % Ensure user does not input invalid image file %


            fullFileName = fullfile(folder,baseFileName)
            app.image = imread(fullFileName)
            % Display the image in UIAxes
          
            imshow(app.image, 'Parent', app.UIAxes);
            app.image = im2double(app.image);
            [rows, cols, ~] = size(app.image);
           
            axis off


            title(app.UIAxes, 'Loaded Image');
            % imshow(app.image)
            % enable buttons after loading
            app.RotateCheckBox.Enable = 'on'
            app.ScalingCheckBox.Enable = 'on'


            % Load image into environment
        end

        % Button pushed function: ResetTransormationsImageButton
        function ResetTransormationsImageButtonPushed(app, event)
            app.transformedImage = app.Image


            % Implement buttoon logic

        end

        % Value changed function: TranslateCheckBox
        function TranslateCheckBoxValueChanged(app, event)
    value = app.TranslateCheckBox.Value;
    app.translateImageActivated = value
    
    if value == 1
        % If button is toggled on
        app.transformCounter = app.transformCounter + 1
    end
    % Disable buttons while processing
    % app.LoadImageButton.Enable = 'off';   
    % app.Button3.Enable = 'off';
    % app.ResizeButton.Enable = 'off';


    % Re-enable buttons after translation
    % app.LoadImageButton.Enable = 'on';
    % app.Button3.Enable = 'on';
    % app.ResizeButton.Enable = 'on';
        end

        % Value changed function: RotateCheckBox
        function RotateCheckBoxValueChanged(app, event)
            value = app.RotateCheckBox.Value;
            app.translateImageActivated = value;


        end

        % Button pushed function: ApplyTransformationsButton
        function ApplyTransformationsButtonPushed(app, event)
            % Check Values of all for 4 check boxes and apply
            % transformations as needed
            % Reflection, Scaling, Rotation, Translation

            if app.rotateImageActivated
                % do rotation

            end


            if app.translateImageActivated 
                % do translation
                applyTranslate(app)
            end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            app.UIAxes_2.Visible = 'off';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.InputXEditFieldLabel = uilabel(app.UIFigure);
            app.InputXEditFieldLabel.Text = 'Input X';
            app.InputXTranslateEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputYEditFieldLabel = uilabel(app.UIFigure);
            app.InputYEditFieldLabel.Text = 'Input Y';
            app.InputYTranslateEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResetTransormationsImageButton = uibutton(app.UIFigure, 'push');
            app.ResetTransormationsImageButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTransormationsImageButtonPushed, true);
            app.ResetTransormationsImageButton.Text = 'Reset Transormations Image';
            app.TranslateLabel = uilabel(app.UIFigure);
            app.TranslateLabel.Text = 'Translate';
            app.TranslateCheckBox = uicheckbox(app.UIFigure);
            app.TranslateCheckBox.ValueChangedFcn = createCallbackFcn(app, @TranslateCheckBoxValueChanged, true);
            app.RotateLabel = uilabel(app.UIFigure);
            app.RotateLabel.Text = 'Rotate';
            app.RotateCheckBox = uicheckbox(app.UIFigure);
            app.RotateCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotateCheckBoxValueChanged, true);
            app.ApplyTransformationsButton = uibutton(app.UIFigure, 'push');
            app.ApplyTransformationsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyTransformationsButtonPushed, true);
            app.ApplyTransformationsButton.Text = 'Apply Transformations';
            app.RotateAngleEditFieldLabel = uilabel(app.UIFigure);
            app.RotateAngleEditFieldLabel.Text = 'Rotate Angle';
            app.RotateAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScalingLabel = uilabel(app.UIFigure);
            app.ScalingLabel.Text = 'Scaling';
            app.ScalingCheckBox = uicheckbox(app.UIFigure);
            app.ReflectionLabel = uilabel(app.UIFigure);
            app.ReflectionLabel.Text = 'Reflection';
            app.ScalingCheckBox_2 = uicheckbox(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end